classdef aggregate_data_daily_and_hourly < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SummarizeDetectionsTab         matlab.ui.container.Tab
        SummarizeButtonGroup           matlab.ui.container.ButtonGroup
        HourlyCheckBox                 matlab.ui.control.CheckBox
        DailyCheckBox                  matlab.ui.control.CheckBox
        SpeciesDropDownLabel           matlab.ui.control.Label
        SpeciesDropDown                matlab.ui.control.DropDown
        RegressionAnalysisSwitchLabel  matlab.ui.control.Label
        RegressionAnalysisSwitch       matlab.ui.control.Switch
        RunButton                      matlab.ui.control.Button
        PressruntoprocessadatabaseLabel  matlab.ui.control.Label
        StatusLampLabel                matlab.ui.control.Label
        StatusLamp                     matlab.ui.control.Lamp
        Image                          matlab.ui.control.Image
        CurrentFilesLoadedTextAreaLabel  matlab.ui.control.Label
        CurrentFilesLoadedTextArea     matlab.ui.control.TextArea
        RegressionAnalysisTab          matlab.ui.container.Tab
        ChooseDatesandHourstoAnalyzeLabel  matlab.ui.control.Label
        DateListBox                    matlab.ui.control.ListBox
        Image_4                        matlab.ui.control.Image
        DateListBoxLabel               matlab.ui.control.Label
        HourListBoxLabel               matlab.ui.control.Label
        HourListBox                    matlab.ui.control.ListBox
        PostRegressionTab              matlab.ui.container.Tab
        SelectFilesButton              matlab.ui.control.Button
        CurrentFilesSelectedTextAreaLabel  matlab.ui.control.Label
        CurrentFilesSelectedTextArea   matlab.ui.control.TextArea
        RunButton_2                    matlab.ui.control.Button
        StatusLamp_2Label              matlab.ui.control.Label
        StatusLamp_2                   matlab.ui.control.Lamp
        PressruntoprocessfilesLabel    matlab.ui.control.Label
        InputResultsFromRegressionAnalysisPanel  matlab.ui.container.Panel
        sbelowthisvaluemarked0asnegativedetectionsLabel  matlab.ui.control.Label
        sabovethisvaluemarked1aspositivedetectionsLabel  matlab.ui.control.Label
        Image_5                        matlab.ui.control.Image
        HigherValueEditFieldLabel      matlab.ui.control.Label
        HigherValueEditField           matlab.ui.control.NumericEditField
        LowerValueEditFieldLabel       matlab.ui.control.Label
        LowerValueEditField            matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        speciesTyp %program type
        datafiles % source data (can be one or multiple files)
        pathname % pathname
        pathname_review % path for review_table function
        filename_review %filename for review_table function
        StartEndTable % start/end table app
        startHour % start/end times for deployment boundaries function
        startDay
        endHour
        endDay
    end
    
    methods (Access = private)
        
        function data4regression(app, pathname_summary, currentfile)
            app.projectname_summary = app.projectname_summary{1};
            hourlyPres= readtable([pathname_summary,app.projectname_summary,'.xlsx'],'Sheet','HourlyPres');
            
            [~,~,dsplit]= ymd(hourlyPres.Day); %just days
            dinterest= str2num(char(app.DateListBox.Value));
            dinterest = dinterest.';
            
            counter= 1;
            daytemp= zeros(1,4)-999;
            for d= 1:length(dinterest)
                indx= find(dsplit == dinterest(d));
                daysnum(counter:counter+length(indx)-1)= datenum(hourlyPres.Day(indx));
                daytemp(counter:counter+length(indx)-1,:)= table2array(hourlyPres(indx,2:5));
                counter= counter+length(indx);
            end
            
            selectDays= [daysnum',daytemp];
            
            %now select only the hours we want
            hinterest= str2num(char(app.HourListBox.Value));%hours Local time: [6,14,22];
            hinterest = hinterest.';
            counter2= 1;
            hourtemp= zeros(1,5)-999;
            for h= 1:length(hinterest)
                indx2= find(selectDays(:,2)==hinterest(h));
                hourtemp(counter2:counter2+length(indx2)-1,:)= selectDays(indx2,:);
                counter2= counter2+length(indx2);
            end
            
            hourtempS= sortrows(hourtemp,[1,2]);

            %add column 6= Present?
            [nrow,~]= size(hourtempS);
            col6= zeros(nrow,1)-999;
            toreview= [hourtempS,col6];
            
            %convert to a table
            toreview= array2table(toreview);
            toreview.Properties.VariableNames= {'Day','Hour','nDet','MedLowFreq',...
                'MedHighFreq','Present'};
            toreview.Day = datetime(toreview.Day,'ConvertFrom','datenum');
            toreview.Day= datestr(toreview.Day,'yyyy-mm-dd');
            
            %export as a new Excel file
            projectname= strsplit(currentfile,'.');
            filename2= [pathname_summary,projectname{1},'_RegressionHours2.xlsx'];
            writetable(toreview,filename2);
        end
        
        
        function writeDailyHourly2File(app, filename_minke, pathname_minke)
            % if isa(filename_minke,'cell')==1
                %multi
                nfiles= length(filename_minke);
                for n= 1:nfiles
                    currentfile_minke = filename_minke{n};
                    app.PressruntoprocessadatabaseLabel.Text = currentfile_minke;
                    drawnow;
                    
                    data= readtable([pathname_minke,currentfile_minke],'ReadVariableNames',0);
                    [dailyResults,hourlyResults]= grabDailyHourlySelections(data);
                    Present= cell(length(dailyResults.Date),1);
                    Present= cell2table(Present);
                    dailyResults= horzcat(dailyResults,Present);
                    
                    Present= cell(length(hourlyResults.Date),1);
                    Present= cell2table(Present);
                    hourlyResults= horzcat(hourlyResults,Present);
                    
                    dailyResults= insertAbsences(app, dailyResults,'daily');
                    
                    singlefile= currentfile_minke;
                    p0= strfind(singlefile,'Minke');
                    projectname= singlefile(1:p0-1);
                    filenamenew= [pathname_minke,projectname,'Minke_cheatSheet.xlsx'];
                    if app.DailyCheckBox.Value == 1
                        writetable(dailyResults,filenamenew,'Sheet','DailyResults')
                    end
                    if app.HourlyCheckBox.Value == 1
                        writetable(hourlyResults,filenamenew,'Sheet','HourlyResults')
                    end
                end
%             else
%                 %single
%                 app.PressruntoprocessadatabaseLabel.Text = filename_minke;
%                 drawnow;
%                 data= readtable([pathname_minke,filename_minke],'ReadVariableNames',0);
%                 [dailyResults,hourlyResults]= grabDailyHourlySelections(data);
%                  Present= cell(length(dailyResults.Date),1);
%                  Present= cell2table(Present);
%                  dailyResults= horzcat(dailyResults,Present);
%                     
%                  Present= cell(length(hourlyResults.Date),1);
%                  Present= cell2table(Present);
%                  hourlyResults= horzcat(hourlyResults,Present);
%                 
%                  dailyResults= insertAbsences(app, dailyResults,'daily');
%                  
%                 p0= strfind(filename_minke,'Minke');
%                 projectname= filename_minke(1:p0-1);
%                 filenamenew= [pathname_minke,projectname,'Minke_cheatSheet.xlsx'];
%                 if app.DailyCheckBox.Value == 1    
%                     writetable(dailyResults,filenamenew,'Sheet','DailyResults')
%                 end   
%                 if app.HourlyCheckBox.Value == 1      
%                     writetable(hourlyResults,filenamenew,'Sheet','HourlyResults')
%              end
        end
        
        function ProcessDolphinFiles(app, pathname_summary, datafiles_summary)
            for  k = 1:length(datafiles_summary)
             currentfile = datafiles_summary{k};
             msg= sprintf('Processing %s',currentfile);
             app.PressruntoprocessadatabaseLabel.Text = msg;
             drawnow;
           
                    %read database            
                    wmd = readSQLiteTable(pathname_summary, currentfile);
                    
                    %summarize data into presence tables
                    [dailyPres, hourlyPresT, projectname] = summarizeDolphinDetectionsFunction(wmd, pathname_summary, currentfile);


                    %make sure which check boxes are ticked and then write
                    %those tables
                    HourlyCheckBoxValue = app.HourlyCheckBox.Value;
                    if HourlyCheckBoxValue == 1 
                        
                        hourlyPresT = deploymentBoundaries(app.startHour(k), app.endHour(k), hourlyPresT) ;
                        writetable(hourlyPresT,[char(pathname_summary),char(projectname{1}),'.xlsx'],'Sheet','HourlyPres');
                    end
                    
                    DailyCheckBoxValue = app.DailyCheckBox.Value;
                    if DailyCheckBoxValue == 1
                        writetable(dailyPres,[char(pathname_summary),char(projectname{1}),'.xlsx'],'Sheet','DailyPres');
                    end

                    %if regression switch is on go to regression function 
                    switch app.RegressionAnalysisSwitch.Value
                        case 'On'
                            data4regression(app, pathname_summary, currentfile);
                        case 'Off' 
                            %don't go to function when switch is off
                    end
            end 
        end
        
     
        function createRowTitles(app, data)
        namearray = {};
        for c = 1:length(data)
            filename = data{c};
            
            [~,Site,Deployment,~] = extractInfoFromFilename(filename);
            
            composite_name = [Site,'_', Deployment];
            namearray = [namearray, composite_name];
            app.rowtitles = namearray;
        end
        end
        
        
        function ProcessDolphinFiles2(app, pathname_summary, datafiles_summary)
            for  k = 1:length(datafiles_summary)
                 currentfile = datafiles_summary{k};
                 msg= sprintf('Processing %s',currentfile);
                 app.PressruntoprocessadatabaseLabel.Text = msg;
                 drawnow;
                 
               
                 [~,~,~,species] = extractInfoFromFilename(currentfile);
                 
                 %read database
                 wmd = readWMD_SQLiteTable_2inputs(pathname_summary,currentfile,species);
                 
                 %summarize into presence tables
                 [dailyPres, hourlyPresT, projectname] = summarizeDolphinDetections4(wmd, currentfile, pathname_summary, species);
                 
                 %make sure which check boxes are ticked and then write
                    %those tables
                    HourlyCheckBoxValue = app.HourlyCheckBox.Value;
                    if HourlyCheckBoxValue == 1 
                        
                        hourlyPresT = deploymentBoundaries(app.startHour(k), app.endHour(k), hourlyPresT) ;
                        writetable(hourlyPresT,[char(pathname_summary),char(projectname{1}),'.xlsx'],'Sheet','HourlyPres');
                    end
                    
                    DailyCheckBoxValue = app.DailyCheckBox.Value;
                    if DailyCheckBoxValue == 1
                        writetable(dailyPres,[char(pathname_summary),char(projectname{1}),'.xlsx'],'Sheet','DailyPres');
                    end

                    %if regression switch is on go to regression function 
                    switch app.RegressionAnalysisSwitch.Value
                        case 'On'
                            data4regression(app, pathname_summary, currentfile);
                        case 'Off' 
                            %don't go to function when switch is off
                    end
            end
        end
    end
    
    methods (Access = public)
        
        function getStartEndHour(app, hourData)        
            app.startHour = hourData(:,1);
            app.endHour = hourData(:,2);
        end
        
        function getStartEndDay(app, dayData)           
            app.startDay = dayData(:,1); 
            app.endDay = dayData(:,2); 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SpeciesDropDown
        function GetFile(app, event)
            %change status lamp to red and say busy
            app.StatusLamp.Color = 'red';
            app.StatusLampLabel.Text = 'Busy';
            drawnow;
            
            app.speciesTyp = app.SpeciesDropDown.Value;
            if   strcmp(app.speciesTyp,'Dolphin')
                %if Pamguard- dolphins
                [app.datafiles, app.pathname]= uigetfile('*.sqlite3','Select the Pamguard database(s)',"MultiSelect","on");
                
                %refocus app as primary window
                figure(app.UIFigure)
                
                %if user selects no file display none in text window 
                if isequal(app.datafiles, 0)
                   app.CurrentFilesLoadedTextArea.Value = 'Please select a source input to load files...';
                else
                   app.CurrentFilesLoadedTextArea.Value = app.datafiles;
                end
                
                if app.speciesTyp == 'Dolphin'
                    app.RegressionAnalysisSwitch.Value = 'Off';
                end
                
                %if 2 pamguard detectors
            elseif strcmp(app.speciesTyp, 'Pamguard 2 detectors')
                [app.datafiles, app.pathname] = uigetfile('*sqlite3','Select the Pamguard database(s)',"MultiSelect","on");
                
                %refocus app as primary window
                figure(app.UIFigure)
                
                %if user selects no file display none in text window 
                if isequal(app.datafiles, 0)
                   app.CurrentFilesLoadedTextArea.Value = 'Please select a source input to load files...';
                else
                   app.CurrentFilesLoadedTextArea.Value = app.datafiles;
                end
                
                if app.speciesTyp == 'Pamguard 2 detectors'
                    app.RegressionAnalysisSwitch.Value = 'Off';
                end
                
                %if XBAT- minkes
            elseif strcmp(app.speciesTyp,'Minke')
                [app.datafiles,  app.pathname]= uigetfile('*.txt','Select the Raven selection table(s)',"MultiSelect","on");
                
                %refocus app as primary window
                figure(app.UIFigure)
                
                %if user selects no file say none in text window
                if isequal(app.datafiles, 0)
                   app.CurrentFilesLoadedTextArea.Value = 'Please select a source input to load files...';
                else
                   app.CurrentFilesLoadedTextArea.Value = app.datafiles;
                end
                
                %if user selects XBAT set
                %regression analysis switch to Off
                if app.speciesTyp == 'Minke'
                    app.RegressionAnalysisSwitch.Value = 'Off';
                end
                
            elseif strcmp(app.speciesTyp, '(none)')
                app.CurrentFilesLoadedTextArea.Value = 'Please select a species to load files...';
                if app.speciesTyp == '(none)'
                    app.RegressionAnalysisSwitch.Value = 'Off';
                end
            else %user left dropdown blank
                
            end
            
          %set status lamp to green and say status  
          app.StatusLamp.Color = 'green';
          app.StatusLampLabel.Text = 'Status';
          drawnow;
            
        end

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            %make status lamp red and say busy
            app.StatusLamp_2.Color = 'red';
            app.StatusLamp_2Label.Text = 'Busy';
            drawnow;
            
            %open browser to retrieve file
            [app.filename_review, app.pathname_review]= uigetfile('*.xlsx','Select the tables','MultiSelect','on');
           
            %convert to cell array so for loop can read it
            app.filename_review = cellstr(app.filename_review);
            
            %if user does not select a file put none in current files
            %selected text area
            if isequal(app.filename_review, 0)
                   app.CurrentFilesSelectedTextArea.Value = 'None. Select a file...';
                else
                   app.CurrentFilesSelectedTextArea.Value = app.filename_review;
            end
            
            %change status lamp to green and say status
            app.StatusLamp_2.Color = 'green';
            app.StatusLamp_2Label.Text = 'Status';
            drawnow;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
        %make the run light red and change text to busy
        app.StatusLamp.Color = 'red';
        app.StatusLampLabel.Text = 'Busy';
        drawnow;

if ((app.HourlyCheckBox.Value == 1) || (app.DailyCheckBox.Value == 1))
    
    %define variables to be sent to Start_End_table
    data = app.datafiles;
    if app.HourlyCheckBox.Value == 1
        HourlyCheckBoxValue = 1;
    else 
        HourlyCheckBoxValue = 0;
    end
    
    if app.DailyCheckBox.Value == 1
        DailyCheckBoxValue = 1;
    else 
        DailyCheckBoxValue = 0;
    end
    
    %Call Start/End tables
    app.StartEndTable = Start_End_Table(app, data, HourlyCheckBoxValue, DailyCheckBoxValue);
    
    %wait for pop-up window to close when user presses enter
    waitfor(app.StartEndTable);
    
end


%run code based on which program type
switch app.speciesTyp
    case 'Dolphin'              
        pathname_summary = app.pathname;
        datafiles_summary = cellstr(app.datafiles);
        ProcessDolphinFiles(app, pathname_summary, datafiles_summary);
        
    case 'Pamguard 2 detectors'
        pathname_summary = app.pathname;
        datafiles_summary = cellstr(app.datafiles);
        ProcessDolphinFiles2(app, pathname_summary, datafiles_summary);

    case 'Minke'
       filename_minke = cellstr(app.datafiles);
       pathname_minke = app.pathname ;
       writeDailyHourly2File(app, filename_minke, pathname_minke)
    case '(none)'
        %do nothing
end    
         %make the run light green and change text to status
         app.SpeciesDropDown.Value = '(none)';
         app.StatusLamp.Color = 'green';
         app.StatusLampLabel.Text = 'Status';
         
         %return label on run button back to default value
         app.PressruntoprocessadatabaseLabel.Text = 'Press run to process a database';
         drawnow;
        end

        % Button pushed function: RunButton_2
        function postRegressionRunPushed(app, event)
        %make run light red and change text to busy    
        app.StatusLamp_2.Color = 'red';
        app.StatusLamp_2Label.Text = 'Busy';
        drawnow;

       for  k = 1:length(app.filename_review)
            %read array of files
            currentfile_review = app.filename_review{k};
             
            %change label to the current file that's being processed
            msg= sprintf('Processing %s',currentfile_review);
            app.PressruntoprocessfilesLabel.Text = msg;
            drawnow;
            
            %set the range of numbers to look at
            lowest_num = app.LowerValueEditField.Value;
            highest_num = app.HigherValueEditField.Value;
            
            %call the postRegression function
            postRegression(app.pathname_review,currentfile_review, lowest_num, highest_num) 
            
        end         
            %change status lamp to green and text to status
            app.StatusLamp_2.Color = 'green';
            app.StatusLamp_2Label.Text = 'Status';
            app.PressruntoprocessfilesLabel.Text = 'Press run to process files';
     
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SummarizeDetectionsTab = uitab(app.TabGroup);
            app.SummarizeDetectionsTab.Title = 'Summarize Detections';
            app.SummarizeButtonGroup = uibuttongroup(app.SummarizeDetectionsTab);
            app.SummarizeButtonGroup.Title = 'Summarize';
            app.HourlyCheckBox = uicheckbox(app.SummarizeButtonGroup);
            app.HourlyCheckBox.Text = 'Hourly';
            app.DailyCheckBox = uicheckbox(app.SummarizeButtonGroup);
            app.DailyCheckBox.Text = 'Daily';
            app.SpeciesDropDownLabel = uilabel(app.SummarizeDetectionsTab);
            app.SpeciesDropDownLabel.Text = 'Species';
            app.SpeciesDropDown = uidropdown(app.SummarizeDetectionsTab);
            app.SpeciesDropDown.Items = {'(none)', 'Dolphin', 'Pamguard 2 detectors', 'Minke'};
            app.SpeciesDropDown.ValueChangedFcn = createCallbackFcn(app, @GetFile, true);
            app.SpeciesDropDown.Value = 'Minke';
            app.RegressionAnalysisSwitchLabel = uilabel(app.SummarizeDetectionsTab);
            app.RegressionAnalysisSwitchLabel.Text = 'Regression Analysis';
            app.RegressionAnalysisSwitch = uiswitch(app.SummarizeDetectionsTab, 'slider');
            app.RegressionAnalysisSwitch.Tooltip = {'go to regression analysis tab if ''On'''};
            app.RunButton = uibutton(app.SummarizeDetectionsTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PressruntoprocessadatabaseLabel = uilabel(app.SummarizeDetectionsTab);
            app.PressruntoprocessadatabaseLabel.Text = 'Press run to process a database';
            app.StatusLampLabel = uilabel(app.SummarizeDetectionsTab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.SummarizeDetectionsTab);
            app.Image = uiimage(app.SummarizeDetectionsTab);
            app.Image.ImageSource = 'PA_Group_logo_lettering_notes_color.png';
            app.CurrentFilesLoadedTextAreaLabel = uilabel(app.SummarizeDetectionsTab);
            app.CurrentFilesLoadedTextAreaLabel.Text = 'Current Files Loaded';
            app.CurrentFilesLoadedTextArea = uitextarea(app.SummarizeDetectionsTab);
            app.CurrentFilesLoadedTextArea.Editable = 'off';
            app.CurrentFilesLoadedTextArea.Value = {'Please select a source input to load files...'; ''};
            app.RegressionAnalysisTab = uitab(app.TabGroup);
            app.RegressionAnalysisTab.Title = 'Regression Analysis';
            app.ChooseDatesandHourstoAnalyzeLabel = uilabel(app.RegressionAnalysisTab);
            app.ChooseDatesandHourstoAnalyzeLabel.Text = 'Choose Dates and Hours to Analyze';
            app.DateListBox = uilistbox(app.RegressionAnalysisTab);
            app.DateListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', ''};
            app.DateListBox.Multiselect = 'on';
            app.DateListBox.Tooltip = {'Select which days you''d like to use'};
            app.DateListBox.Value = {'1'};
            app.DateListBoxLabel = uilabel(app.RegressionAnalysisTab);
            app.DateListBoxLabel.Text = 'Date ';
            app.HourListBoxLabel = uilabel(app.RegressionAnalysisTab);
            app.HourListBoxLabel.Text = 'Hour';
            app.HourListBox = uilistbox(app.RegressionAnalysisTab);
            app.HourListBox.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', ''};
            app.HourListBox.Multiselect = 'on';
            app.HourListBox.Tooltip = {'Select which hours you''d like to use'};
            app.HourListBox.Value = {'0'};
            app.Image_4 = uiimage(app.RegressionAnalysisTab);
            app.Image_4.ImageSource = 'PA_Group_logo_lettering_notes_color.png';
            app.PostRegressionTab = uitab(app.TabGroup);
            app.PostRegressionTab.Title = 'Post-Regression';
            app.SelectFilesButton = uibutton(app.PostRegressionTab, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files...';
            app.CurrentFilesSelectedTextAreaLabel = uilabel(app.PostRegressionTab);
            app.CurrentFilesSelectedTextAreaLabel.Text = 'Current Files Selected';
            app.CurrentFilesSelectedTextArea = uitextarea(app.PostRegressionTab);
            app.CurrentFilesSelectedTextArea.Editable = 'off';
            app.CurrentFilesSelectedTextArea.Value = {'None. Select a file...'};
            app.RunButton_2 = uibutton(app.PostRegressionTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @postRegressionRunPushed, true);
            app.RunButton_2.Text = 'Run';
            app.StatusLamp_2Label = uilabel(app.PostRegressionTab);
            app.StatusLamp_2Label.Text = 'Status';
            app.StatusLamp_2 = uilamp(app.PostRegressionTab);
            app.PressruntoprocessfilesLabel = uilabel(app.PostRegressionTab);
            app.PressruntoprocessfilesLabel.Text = 'Press run to process files';
            app.InputResultsFromRegressionAnalysisPanel = uipanel(app.PostRegressionTab);
            app.InputResultsFromRegressionAnalysisPanel.Title = 'Input Results From Regression Analysis';
            app.sbelowthisvaluemarked0asnegativedetectionsLabel = uilabel(app.InputResultsFromRegressionAnalysisPanel);
            app.sbelowthisvaluemarked0asnegativedetectionsLabel.Text = '#''s below this value marked ''0'' as negative detections ';
            app.sabovethisvaluemarked1aspositivedetectionsLabel = uilabel(app.InputResultsFromRegressionAnalysisPanel);
            app.sabovethisvaluemarked1aspositivedetectionsLabel.Text = '#''s above this value marked ''1'' as positive detections ';
            app.HigherValueEditFieldLabel = uilabel(app.InputResultsFromRegressionAnalysisPanel);
            app.HigherValueEditFieldLabel.Text = 'Higher Value';
            app.HigherValueEditField = uieditfield(app.InputResultsFromRegressionAnalysisPanel, 'numeric');
            app.LowerValueEditFieldLabel = uilabel(app.InputResultsFromRegressionAnalysisPanel);
            app.LowerValueEditFieldLabel.Text = 'Lower Value';
            app.LowerValueEditField = uieditfield(app.InputResultsFromRegressionAnalysisPanel, 'numeric');
            app.Image_5 = uiimage(app.PostRegressionTab);
            app.Image_5.ImageSource = 'PA_Group_logo_lettering_notes_color.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aggregate_data_daily_and_hourly

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end